classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        Tab_3            matlab.ui.container.Tab
        Image2           matlab.ui.control.Image
        Label            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Label_3          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        DFTTab           matlab.ui.container.Tab
        UIAxes           matlab.ui.control.UIAxes
        NEditFieldLabel  matlab.ui.control.Label
        NEditField       matlab.ui.control.NumericEditField
        Button_1_1       matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        Image            matlab.ui.control.Image
        Tab_2            matlab.ui.container.Tab
        Button_2_1       matlab.ui.control.Button
        Button_2_2       matlab.ui.control.Button
        Button_2_3       matlab.ui.control.Button
        Button_2_4       matlab.ui.control.Button
        UIAxes2_3        matlab.ui.control.UIAxes
        UIAxes2_4        matlab.ui.control.UIAxes
        Button_2_5       matlab.ui.control.Button
        UIAxes2_1        matlab.ui.control.UIAxes
        UIAxes2_5        matlab.ui.control.UIAxes
        UIAxes2_2        matlab.ui.control.UIAxes
        UIAxes2_6        matlab.ui.control.UIAxes
        Button_2_6       matlab.ui.control.Button
        Button_2_7       matlab.ui.control.Button
        Button_2_8       matlab.ui.control.Button
        Slider_2Label    matlab.ui.control.Label
        Slider_2_1       matlab.ui.control.Slider
        Tab              matlab.ui.container.Tab
        Button_3_1       matlab.ui.control.Button
        Button_3_2       matlab.ui.control.Button
        UIAxes3_1        matlab.ui.control.UIAxes
        UIAxes3_2        matlab.ui.control.UIAxes
        DLabel           matlab.ui.control.Label
        EditField_3_1    matlab.ui.control.NumericEditField
        Button_3_3       matlab.ui.control.Button
        UIAxes3_3        matlab.ui.control.UIAxes
        UIAxes3_4        matlab.ui.control.UIAxes
        Button_3_4       matlab.ui.control.Button
        UIAxes3_5        matlab.ui.control.UIAxes
        UIAxes3_6        matlab.ui.control.UIAxes
        Button_3_5       matlab.ui.control.Button
        UIAxes3_7        matlab.ui.control.UIAxes
        UIAxes3_8        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fs     %源文件频率
        audio  %音频文件
        info   %歌曲信息
        audiolength
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            N = app.NEditField.Value;
            
        end

        % Button pushed function: Button_1_1
        function Button_1_1Pushed(app, event)
            N =  app.NEditField.Value;           
            n=1:1:N;
            k_0=0:1:N-1;
            x_n=cos(7*pi.*n/16)+0.5*cos(9*pi.*n/16)+0.75*cos(pi.*n/2);            
            X=fft(x_n,N);
            k=0:1:(N/2);
            w=2*k*pi/N;
            magX=abs(X(1:1:(N/2+1)));            
            stem(app.UIAxes,w/pi,magX);
            stem(app.UIAxes2,n,x_n);
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            N = app.Slider.Value;
            app.NEditField.Value = app.Slider.Value;
        end

        % Button pushed function: Button_2_1
        function Button_2_1Pushed(app, event)
            clear sound;
           
            %读取音频文件
           filterspec = {'*.mp3;*.wav;*.m4a','All app.audio Files'};
           [F, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               song = [p F];
               
               [app.audio,app.Fs]=audioread(song);
              
            end


            sound(app.Slider_2_1.Value/100*app.audio,app.Fs);%播放音频文件
            app.audiolength = length(app.audio);%获取音频文件的数据长度
            t = 1:1:app.audiolength;           
            plot(app.UIAxes2_1,t,app.audio);
            y =abs(fft(app.audio));
            %y=20*log(abs(y))/log(10);        %换算成dBW单位
            f=[0:(app.Fs/app.audiolength):app.Fs];     %转换横坐标以Hz为单位
            y=y(1:length(f));
            plot(app.UIAxes2_2,f,y)
        end

        % Button pushed function: Button_2_2
        function Button_2_2Pushed(app, event)
            clear sound;
        end

        % Button pushed function: Button_2_3
        function Button_2_3Pushed(app, event)
            %此为添加白噪音的按钮
            clear sound;
            
            snr=30; %信噪比
            audio1=awgn(app.audio,snr); %产生添加白噪音后的音频
            app.audiolength = length(audio1);%获取音频文件的数据长度
            t = 1:1:app.audiolength;
            sound(app.Slider_2_1.Value/100*audio1,app.Fs);
            plot(app.UIAxes2_3,t,audio1);%绘时域图
            y = abs(fft(audio1));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes2_4,f,y); %绘频域图
        end

        % Button pushed function: Button_2_4
        function Button_2_4Pushed(app, event)
            %此为增添单频噪音按钮
            
            clear sound;
            
            t = 1:1:app.audiolength;
            noise = 0.17*sin(2*pi*10000*(1:size(app.audio))/app.Fs); %单频噪音，频率为1kHz
            audio1=app.audio+noise';
            sound(app.Slider_2_1.Value/100*audio1,app.Fs);
            plot(app.UIAxes2_3,t,audio1);
            y = abs(fft(audio1));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes2_4,f,y);
        end

        % Button pushed function: Button_2_5
        function Button_2_5Pushed(app, event)
            %此为增加多频噪音按钮
            
            clear sound;
            
            t = 1:1:app.audiolength;
            noise = 0.08*sin(2*pi*10000*(1:size(app.audio))/app.Fs)+0.02*sin(2*pi*8096*(1:size(app.audio))/app.Fs)+0.03*sin(2*pi*8196*(1:size(app.audio))/app.Fs); %多频噪音，包括1000、1500、2048Hz的噪音组合
            audio1=app.audio+noise';
            sound(app.Slider_2_1.Value/100*audio1,app.Fs);
            plot(app.UIAxes2_3,t,audio1);
            y = abs(fft(audio1));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes2_4,f,y);
        end

        % Button pushed function: Button_2_6
        function Button_2_6Pushed(app, event)
            %此为添加白噪音的按钮
            clear sound;            
            snr=30; %信噪比
            audio1=awgn(app.audio,snr); %产生添加白噪音后的音频
            app.audiolength = length(audio1);%获取音频文件的数据长度
            t = 1:1:app.audiolength;
                       
            fp1=1000;fs1=1600;
            rp=0.1;
            rs=40;
            wp=2*fp1/app.Fs;ws=2*fs1/app.Fs;
            [N1,wc]=buttord(wp,ws,rp,rs);
            [b,a]=butter(N1,wc,'low');

            %用巴特沃斯滤波器滤除高斯白噪声
            
            z=filter(b,a,audio1);
                        
            sound(app.Slider_2_1.Value/100*z,app.Fs);
            plot(app.UIAxes2_5,t,z);
            y = abs(fft(z));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes2_6,f,y);
        end

        % Button pushed function: Button_2_7
        function Button_2_7Pushed(app, event)
            %此为滤除单频噪音按钮
            %以下为读取单频噪音
            
            clear sound;
   
            t = 1:1:app.audiolength;
            noise = 0.17*sin(2*pi*10000*(1:size(app.audio))/app.Fs); %单频噪音，频率为1kHz
            audio1=app.audio+noise';
            %采用巴特沃斯滤波器，以下为参数
            fp=8000; %通带截止频率
            fs=9500; %阻带截止频率
            rp=0.1;  %通带最大衰减
            rs=50;   %阻带最小衰减
            wp=fp*2/app.Fs;
            ws=fs*2/app.Fs;
            [N1,wc]=buttord(wp,ws,rp,rs);
            [b,a]=butter(N1,wc);
           
            %以下为滤波并输出滤波后的声音
            z=filter(b,a,audio1);
            
            
            sound(app.Slider_2_1.Value/100*z,app.Fs);
            plot(app.UIAxes2_5,t,z);
            y = abs(fft(z));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes2_6,f,y);
            
        end

        % Button pushed function: Button_2_8
        function Button_2_8Pushed(app, event)
             %此为滤除多频噪音按钮
             %以下为读取多频噪音
           
            clear sound;
  
            t = 1:1:app.audiolength;
            noise = 0.08*sin(2*pi*10000*(1:size(app.audio))/app.Fs)+0.02*sin(2*pi*8096*(1:size(app.audio))/app.Fs)+0.03*sin(2*pi*8196*(1:size(app.audio))/app.Fs); %多频噪音，包括10000、8096、2048Hz的噪音组合
            audio1=app.audio+noise';
             
            %采用巴特沃斯滤波器，以下为参数
            fp=5000; %通带截止频率
            fs=6000; %阻带截止频率
            rp=0.1;  %通带最大衰减
            rs=50;   %阻带最小衰减
            wp=fp*2/app.Fs;
            ws=fs*2/app.Fs;
            [N1,wc]=buttord(wp,ws,rp,rs);
            [b,a]=butter(N1,wc);
            
                   
            %以下为滤波并输出滤波后的声音
            z=filter(b,a,audio1);           
            
            sound(app.Slider_2_1.Value/100*z,app.Fs);
            plot(app.UIAxes2_5,t,z);
            y = abs(fft(z));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes2_6,f,y);
        end

        % Value changed function: Slider_2_1
        function Slider_2_1ValueChanged(app, event)
            value = app.Slider_2_1.Value;
           
        end

        % Button pushed function: Button_3_1
        function Button_3_1Pushed(app, event)
           filterspec = {'*.mp3;*.wav;*.m4a','All app.audio Files'};
           [F, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               song = [p F];
               
               [app.audio,app.Fs]=audioread(song);
              
            end
             app.audiolength = length(app.audio);%获取音频文件的数据长度n
            
            sound(app.audio,app.Fs);
            t = 1:1:app.audiolength;           
            plot(app.UIAxes3_1,t,app.audio);
            y =abs(fft(app.audio));
            %y=20*log(abs(y))/log(10);        %换算成dBW单位
            f=[0:(app.Fs/app.audiolength):app.Fs];     %转换横坐标以Hz为单位
            y=y(1:length(f));
            plot(app.UIAxes3_2,f,y)
        end

        % Button pushed function: Button_3_2
        function Button_3_2Pushed(app, event)
            clear sound;
        end

        % Button pushed function: Button_3_3
        function Button_3_3Pushed(app, event)
            clear sound;
            D = app.EditField_3_1.Value;
            audio1 = downsample(app.audio,D);
            tD = 1:1:length(audio1);
            plot(app.UIAxes3_3,tD,audio1);
            y =abs(fft(audio1));
            %y=20*log(abs(y))/log(10);        %换算成dBW单位
            f=[0:(app.Fs/length(y)):app.Fs*(length(y)-1)/(length(y))];     %转换横坐标以Hz为单位
            sound(audio1,app.Fs/D);
            
            plot(app.UIAxes3_4,f,y);
        end

        % Value changed function: EditField_3_1
        function EditField_3_1ValueChanged(app, event)

            value = app.EditField_3_1.Value;
            
        end

        % Button pushed function: Button_3_4
        function Button_3_4Pushed(app, event)
            clear sound;
            D=app.EditField_3_1.Value;
            I=D;
            audio1 = downsample(app.audio,D);
            audio2 = upsample(audio1,I);
            tD = 1:1:length(audio2);
            plot(app.UIAxes3_5,tD,audio2);
            y =abs(fft(audio2));
            %y=20*log(abs(y))/log(10);        %换算成dBW单位
            f=[0:(app.Fs/length(y)):app.Fs*(length(y)-1)/(length(y))];     %转换横坐标以Hz为单位
            sound(audio2,app.Fs);
            
            plot(app.UIAxes3_6,f,y);
        end

        % Callback function
        function Button_2_9Pushed(app, event)
           clear sound;
           ws1=2*pi*20/app.Fs;   %阻带截止频率下限
           ws2=2*pi*7000/app.Fs;   %阻带截止频率上限
           wpl=2*pi*40/app.Fs;  %通带截止频率下限
           wph=2*pi*6000/app.Fs;  %通带截止频率上限
           wp=[wpl wph];
           ws=[ws1 ws2];

           % 设计切比雪夫滤波器；
           [n,wp]=cheb1ord(wp/pi,ws/pi,2,40); % 通带边衰减DB值和阻带边衰减DB值rp=2;rs=40
           [b,a]=cheby1(n,2,wp);
           z=filter(b,a,app.audio);           
            
            t = 1:1:length(z);
            sound(app.Slider_2_1.Value/100*z,app.Fs);
            plot(app.UIAxes2_5,t,z);
            y = abs(fft(z));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes2_6,f,y);
        end

        % Button pushed function: Button_3_5
        function Button_3_5Pushed(app, event)
            clear sound;
            D=app.EditField_3_1.Value;
            I=D;
            audio1 = downsample(app.audio,D);
            audio2 = upsample(audio1,I);
            t = 1:1:length(audio2);
            l = intfilt(I,22/I,1/2);
            audio3 = filter(l,1,audio2);
            sound(audio3,app.Fs);
            plot(app.UIAxes3_7,t,audio3);
            y = abs(fft(audio3));
            f=[0:(app.Fs/app.audiolength):app.Fs];
            y=y(1:length(f));
            plot(app.UIAxes3_8,f,y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Tab';
            app.Image2 = uiimage(app.Tab_3);
            app.Image2.ImageSource = 'csu.gif';
            app.Label = uilabel(app.Tab_3);
            app.Label.Text = '周锐纵';
            app.Label_2 = uilabel(app.Tab_3);
            app.Label_2.Text = '电信1901';
            app.Label_3 = uilabel(app.Tab_3);
            app.Label_3.Text = '选 题 三';
            app.Label_4 = uilabel(app.Tab_3);
            app.Label_4.Text = '数字信号处理 课程设计';
            app.DFTTab = uitab(app.TabGroup);
            app.DFTTab.Title = 'DFT幅频特性';
            app.UIAxes = uiaxes(app.DFTTab);
            title(app.UIAxes, 'DFT幅频特性图')
            xlabel(app.UIAxes, {'ω∕2π'; ''})
            ylabel(app.UIAxes, '幅度')
            app.NEditFieldLabel = uilabel(app.DFTTab);
            app.NEditFieldLabel.Text = '信号数据长度 N';
            app.NEditField = uieditfield(app.DFTTab, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 32;
            app.Button_1_1 = uibutton(app.DFTTab, 'push');
            app.Button_1_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1_1Pushed, true);
            app.Button_1_1.Text = '绘制';
            app.UIAxes2 = uiaxes(app.DFTTab);
            title(app.UIAxes2, '原序列波形')
            xlabel(app.UIAxes2, 'n')
            ylabel(app.UIAxes2, 'x(n)')
            app.Image = uiimage(app.DFTTab);
            app.Image.ImageSource = '1.png';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '语音信号处理';
            app.Button_2_1 = uibutton(app.Tab_2, 'push');
            app.Button_2_1.ButtonPushedFcn = createCallbackFcn(app, @Button_2_1Pushed, true);
            app.Button_2_1.Text = '原音频信号';
            app.Button_2_2 = uibutton(app.Tab_2, 'push');
            app.Button_2_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2_2Pushed, true);
            app.Button_2_2.Text = '停止播放';
            app.Button_2_3 = uibutton(app.Tab_2, 'push');
            app.Button_2_3.ButtonPushedFcn = createCallbackFcn(app, @Button_2_3Pushed, true);
            app.Button_2_3.Text = '添加白噪声';
            app.Button_2_4 = uibutton(app.Tab_2, 'push');
            app.Button_2_4.ButtonPushedFcn = createCallbackFcn(app, @Button_2_4Pushed, true);
            app.Button_2_4.Text = '添加单频噪声';
            app.UIAxes2_3 = uiaxes(app.Tab_2);
            title(app.UIAxes2_3, '叠加噪声后时域波形')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            app.UIAxes2_4 = uiaxes(app.Tab_2);
            title(app.UIAxes2_4, '叠加噪声后频域波形')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            app.UIAxes2_4.XTick = [0 0.5 1];
            app.Button_2_5 = uibutton(app.Tab_2, 'push');
            app.Button_2_5.ButtonPushedFcn = createCallbackFcn(app, @Button_2_5Pushed, true);
            app.Button_2_5.Text = '添加多频噪声';
            app.UIAxes2_1 = uiaxes(app.Tab_2);
            title(app.UIAxes2_1, '原信号时域波形')
            xlabel(app.UIAxes2_1, '')
            ylabel(app.UIAxes2_1, '')
            app.UIAxes2_5 = uiaxes(app.Tab_2);
            title(app.UIAxes2_5, '滤波后时域波形')
            xlabel(app.UIAxes2_5, '')
            ylabel(app.UIAxes2_5, '')
            app.UIAxes2_2 = uiaxes(app.Tab_2);
            title(app.UIAxes2_2, '原信号频域波形')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [0 0.5 1];
            app.UIAxes2_6 = uiaxes(app.Tab_2);
            title(app.UIAxes2_6, '滤波后频域波形')
            xlabel(app.UIAxes2_6, '')
            ylabel(app.UIAxes2_6, '')
            app.UIAxes2_6.XTick = [0 0.5 1];
            app.Button_2_6 = uibutton(app.Tab_2, 'push');
            app.Button_2_6.ButtonPushedFcn = createCallbackFcn(app, @Button_2_6Pushed, true);
            app.Button_2_6.Text = '滤除白噪声';
            app.Button_2_7 = uibutton(app.Tab_2, 'push');
            app.Button_2_7.ButtonPushedFcn = createCallbackFcn(app, @Button_2_7Pushed, true);
            app.Button_2_7.Text = '滤除单频噪声';
            app.Button_2_8 = uibutton(app.Tab_2, 'push');
            app.Button_2_8.ButtonPushedFcn = createCallbackFcn(app, @Button_2_8Pushed, true);
            app.Button_2_8.Text = '滤除多频噪声';
            app.Slider_2Label = uilabel(app.Tab_2);
            app.Slider_2Label.Text = '音量';
            app.Slider_2_1 = uislider(app.Tab_2);
            app.Slider_2_1.ValueChangedFcn = createCallbackFcn(app, @Slider_2_1ValueChanged, true);
            app.Slider_2_1.Value = 100;
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '多采样率信号处理';
            app.Button_3_1 = uibutton(app.Tab, 'push');
            app.Button_3_1.ButtonPushedFcn = createCallbackFcn(app, @Button_3_1Pushed, true);
            app.Button_3_1.Text = '读取语音文件';
            app.Button_3_2 = uibutton(app.Tab, 'push');
            app.Button_3_2.ButtonPushedFcn = createCallbackFcn(app, @Button_3_2Pushed, true);
            app.Button_3_2.Text = '停止播放';
            app.UIAxes3_1 = uiaxes(app.Tab);
            title(app.UIAxes3_1, '原音频时域波形')
            xlabel(app.UIAxes3_1, '')
            ylabel(app.UIAxes3_1, '')
            app.UIAxes3_2 = uiaxes(app.Tab);
            title(app.UIAxes3_2, '原音频频域波形')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.DLabel = uilabel(app.Tab);
            app.DLabel.Text = '抽取因子D';
            app.EditField_3_1 = uieditfield(app.Tab, 'numeric');
            app.EditField_3_1.ValueChangedFcn = createCallbackFcn(app, @EditField_3_1ValueChanged, true);
            app.Button_3_3 = uibutton(app.Tab, 'push');
            app.Button_3_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3_3Pushed, true);
            app.Button_3_3.Text = '抽取信号';
            app.UIAxes3_3 = uiaxes(app.Tab);
            title(app.UIAxes3_3, '信号抽取后时域波形')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, '')
            app.UIAxes3_4 = uiaxes(app.Tab);
            title(app.UIAxes3_4, '信号抽取后频域波形')
            xlabel(app.UIAxes3_4, '')
            ylabel(app.UIAxes3_4, '')
            app.Button_3_4 = uibutton(app.Tab, 'push');
            app.Button_3_4.ButtonPushedFcn = createCallbackFcn(app, @Button_3_4Pushed, true);
            app.Button_3_4.Text = '插值信号';
            app.UIAxes3_5 = uiaxes(app.Tab);
            title(app.UIAxes3_5, '信号插值后时域波形')
            xlabel(app.UIAxes3_5, '')
            ylabel(app.UIAxes3_5, '')
            app.UIAxes3_6 = uiaxes(app.Tab);
            title(app.UIAxes3_6, '信号插值后频域波形')
            xlabel(app.UIAxes3_6, '')
            ylabel(app.UIAxes3_6, '')
            app.Button_3_5 = uibutton(app.Tab, 'push');
            app.Button_3_5.ButtonPushedFcn = createCallbackFcn(app, @Button_3_5Pushed, true);
            app.Button_3_5.Text = '滤波';
            app.UIAxes3_7 = uiaxes(app.Tab);
            title(app.UIAxes3_7, '滤波后时域波形')
            xlabel(app.UIAxes3_7, '')
            ylabel(app.UIAxes3_7, '')
            app.UIAxes3_8 = uiaxes(app.Tab);
            title(app.UIAxes3_8, '滤波后频域波形')
            xlabel(app.UIAxes3_8, '')
            ylabel(app.UIAxes3_8, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end